classdef spineDetectionGUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        EditSpinesPanel        matlab.ui.container.Panel
        CancelButton           matlab.ui.control.Button
        ConfirmButton          matlab.ui.control.Button
        ChangeToDropDown       matlab.ui.control.DropDown
        ChangeToDropDownLabel  matlab.ui.control.Label
        RecEditField           matlab.ui.control.NumericEditField
        RecEditFieldLabel      matlab.ui.control.Label
        AcceptCloseButton      matlab.ui.control.Button
        EditButton             matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        PreviousButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        avg_spines % spines in gaussian averaged projection
        binary_spines % binarized spines
        superResolution_spines % superResolution spines
        cycle_data % estrous cycle stage
        curr_idx % recording number
        classifications % spine/no spine classifications for all spines
        classifications_changed % flags location of changed classifications
        centers % centroids for all spine images
        select_spine_data % spine data just for selected spines (for output structure)
    end
    
    methods (Access = private)
        
        function plotSpines(app)
            app.Panel.AutoResizeChildren = 'off';
            curr_avg_spines = squeeze(app.avg_spines(:,:,:,app.curr_idx));
            curr_binary_spines = squeeze(app.binary_spines(:,:,:,app.curr_idx));
            curr_superResolution_spines = squeeze(app.superResolution_spines(:,:,:,app.curr_idx));
            avg_idx = zeros(size(app.avg_spines,3),4); % 4 columns for each position idx
            superResolution_idx = zeros(size(app.avg_spines,3),4); 
            binary_idx = zeros(size(app.avg_spines,3),4); 
            panelsize = app.Panel.Position;
            edgebuffer = 50; % number of pixels to leave on top and bottom of panel
            imbuffer = 25; % number of pixels to leave between images
            % maxsize = 68;
            minimsperrow = 8; % otherwise it looks awkward
            imsperrow = ceil(size(app.avg_spines,3));
            if imsperrow > minimsperrow
                imsperrow = minimsperrow;
            end
            % pixel_spacing = floor(panelsize(3)/imsperrow); % width over number of images
            %imsize = pixel_spacing - imbuffer; % size of square image (imsize x imsize) 
            %if imsize > maxsize
            %    imsize = maxsize; % so that images don't overlap vertically
            %end
            if imsperrow <= 4
                imsize = 170;
            elseif imsperrow >= 8
                imsize = 68;
            else
                imsize = floor((panelsize(3) - imbuffer*(2 + imsperrow))/imsperrow);
            end
            
            for i = 1:size(app.avg_spines,3) % set position indices for each type of image
                if i > imsperrow
                    startheight = floor(panelsize(4)/2) - (imsize+edgebuffer); % second row starts halfway down
                else
                    startheight = panelsize(4) - (edgebuffer+imsize); 
                end
                if i > imsperrow
                    startwidth = imbuffer*(i-imsperrow)+imsize*((i-imsperrow)-1);
                else
                    startwidth = imbuffer*i+imsize*(i-1);
                end
                avg_coords = [startwidth, startheight, imsize, imsize];
                superResolution_coords = avg_coords;
                superResolution_coords(2) = avg_coords(2) - (imsize+imbuffer);
                binary_coords = avg_coords;
                binary_coords(2) = avg_coords(2) - (imsize*2+imbuffer*2);
                avg_idx(i,:) = avg_coords;
                superResolution_idx(i,:) =  superResolution_coords ;
                binary_idx(i,:) =  binary_coords;
            end

            for ii = 1:size(app.avg_spines,3) % for all recordings
                curr_classification = app.classifications{ii,app.curr_idx};
                curr_stage = app.cycle_data{ii};
                curr_avg_spineByDay = curr_avg_spines(:,:,ii);
                curr_binary_spineByDay = curr_binary_spines(:,:,ii);
                curr_superResolution_spineByDay = curr_superResolution_spines(:,:,ii);
                ax1 = axes('Units', 'pixels', 'Parent',app.Panel);
                ax1.Position = avg_idx(ii,:);
                
                ax2 = axes('Units', 'pixels', 'Parent',app.Panel);
                ax2.Position = superResolution_idx(ii,:);
                
                ax3 = axes('Units', 'pixels', 'Parent',app.Panel);
                ax3.Position = binary_idx(ii,:);

                % plot gaussian averaged spines
                imagesc(ax1,curr_avg_spineByDay)
                colormap(ax1,gray)
                ax1.XTick = [];
                ax1.YTick = [];
                title(ax1,['Rec.', ' ', num2str(ii)],'FontSize',13);
                
                % plot superResolution spines
                imagesc(ax2,curr_superResolution_spineByDay)
                colormap(ax2,gray)
                ax2.XTick = [];
                ax2.YTick = [];
                
                % plot binarized spines and spine classification
                imagesc(ax3,curr_binary_spineByDay)
                colormap(ax3,gray)
                ax3.XTick = [];
                ax3.YTick = [];
                if strcmp(curr_classification,'Spine') % make label green if it's a spine and red if not
                    xlabel(ax3,curr_classification,'Color','#228C22','FontSize',12)
                else
                    xlabel(ax3,curr_classification,'Color','r','FontSize',12)
                end
            end
        end

        function update(app)
            if app.curr_idx == size(app.avg_spines,4) % Next button disabled when you're at last image
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on'; % enabled otherwise
            end
            if app.curr_idx == 1
                app.PreviousButton.Enable = 'off'; % Prev button disabled when you're at 1st image
            else
                app.PreviousButton.Enable = 'on';  % enabled otherwise
            end
            app.Label.Text = ['Spine', ' ', num2str(app.curr_idx)];
            drawnow();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, avg_registered, binary_registered, superResolution_registered, cycle_data, classifications, centers, select_spine_data)
            app.EditSpinesPanel.Visible = 'off'; % dropdown starts off invisible
            app.PreviousButton.Enable = 'off'; % previous button starts off disabled
            
            app.avg_spines = avg_registered;
            app.binary_spines = binary_registered;
            app.superResolution_spines = superResolution_registered;
            app.cycle_data = cycle_data;
            app.classifications = classifications;
            app.centers = centers;
            app.curr_idx = 1;
            app.classifications_changed = zeros(size(app.classifications));
            app.select_spine_data = select_spine_data;

            app.Label.Text = ['Spine', ' ', num2str(app.curr_idx)];

            plotSpines(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.curr_idx = app.curr_idx - 1;
            app.curr_idx = max(app.curr_idx, 1); % prevent curr_idx from passing bounds
            arrayfun(@delete,findall(0,'type','axes')) % clear panel of next figure
            app.plotSpines();
            app.update();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.curr_idx = app.curr_idx + 1;
            app.curr_idx = min(app.curr_idx, size(app.avg_spines,4)); % prevent curr_idx from passing bounds
            arrayfun(@delete,findall(0,'type','axes')) % clear panel of previous figure
            app.plotSpines();
            app.update();
        end

        % Button pushed function: AcceptCloseButton
        function AcceptCloseButtonPushed(app, event)
            data = struct;
            data.classifications = app.classifications;
            data.classifications_changed = app.classifications_changed;
            data.centers = app.centers;
            data.select_spine_data = app.select_spine_data;
            data.spine_images = app.avg_spines;
            data.binary_spine_images = app.binary_spines;
            data.superResolution_spine_images = app.superResolution_spines;
            save('SpineClassifications.mat','data')
            disp('Output data saved to base directory')
            close(app.UIFigure) % close figure
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.EditSpinesPanel.Visible = "on";
            app.RecEditField.Limits = [1,size(app.avg_spines,3)];
            %prompt = {'Enter recording to edit:','Enter new classification:'};
            %dlgtitle = 'Spine Editor';
            %fieldsize = [1 45; 1 45];
            %definput = {'1','No Spine'};
            %answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            %if(isempty(answer))
                %return;
            %end
            %app.classifications(str2double(answer{1}),app.curr_idx) = answer(2); % change classifications to match 
            %app.classifications_changed(str2double(answer{1}),app.curr_idx) = 1;
            %if strcmp(answer{2},'No Spine')
                %app.select_spine_data(str2double(answer{1}),app.curr_idx) = {[]}; % erase spine info if changed to no spine
            %end
            %arrayfun(@delete,findall(0,'type','axes')) % clear panel of previous figure
            %app.plotSpines(); % plot with new classifications
        end

        % Button pushed function: CancelButton
        function SpineCancel(app, event)
            app.EditSpinesPanel.Visible = "off";
        end

        % Button pushed function: ConfirmButton
        function SpineConfirm(app, event)
            app.classifications(app.RecEditField.Value,app.curr_idx) = {app.ChangeToDropDown.Value}; % change classifications to match 
            app.classifications_changed(app.RecEditField.Value,app.curr_idx) = 1;
            if strcmp(app.ChangeToDropDown.Value,'No Spine')
                app.select_spine_data(app.RecEditField.Value,app.curr_idx) = {[]}; % erase spine info if changed to no spine
            end
            arrayfun(@delete,findall(0,'type','axes')) % clear panel of previous figure
            app.plotSpines(); % plot with new classifications
            app.EditSpinesPanel.Visible = "off";
        end

        % Callback function
        function ChangeToDropDownClicked(app, event)
            
        end

        % Callback function
        function SpineChanged(app, event)


        end

        % Callback function
        function RecordingChange(app, event)
         
            
        end

        % Callback function
        function ChangeToDropDownClicked2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.AcceptCloseButton = uibutton(app.UIFigure, 'push');
            app.AcceptCloseButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptCloseButtonPushed, true);
            app.AcceptCloseButton.Text = 'Accept & Close';
            app.Panel = uipanel(app.UIFigure);
            app.EditSpinesPanel = uipanel(app.Panel);
            app.EditSpinesPanel.Title = 'Edit Spines';
            app.RecEditFieldLabel = uilabel(app.EditSpinesPanel);
            app.RecEditFieldLabel.Text = 'Rec. #';
            app.RecEditField = uieditfield(app.EditSpinesPanel, 'numeric');
            app.RecEditField.Limits = [1 20];
            app.RecEditField.ValueDisplayFormat = '%11g';
            app.RecEditField.Value = 1;
            app.ChangeToDropDownLabel = uilabel(app.EditSpinesPanel);
            app.ChangeToDropDownLabel.Text = 'Change To';
            app.ChangeToDropDown = uidropdown(app.EditSpinesPanel);
            app.ChangeToDropDown.Items = {'Spine', 'No Spine'};
            app.ChangeToDropDown.Placeholder = 'Choose Classification';
            app.ChangeToDropDown.Value = 'Spine';
            app.ConfirmButton = uibutton(app.EditSpinesPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @SpineConfirm, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.EditSpinesPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @SpineCancel, true);
            app.CancelButton.Text = 'Cancel';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spineDetectionGUI_v2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end